classdef Aplicacion_1_tiempo_real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpcionesdeconfiguracinPanel     matlab.ui.container.Panel
        OpcionesdevisualizacinButtonGroup  matlab.ui.container.ButtonGroup
        TrayectoriaseguidaporelganchoocargaButton  matlab.ui.control.ToggleButton
        VolumendebsquedaycolisionesButton  matlab.ui.control.ToggleButton
        RielcarroganchoyocargaButton    matlab.ui.control.ToggleButton
        NubedepuntosButton              matlab.ui.control.ToggleButton
        DistanciadeseguridadPanel       matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        dmEditField                     matlab.ui.control.NumericEditField
        dmLabel                         matlab.ui.control.Label
        TamaodebsquedaPanel             matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        tmEditField                     matlab.ui.control.NumericEditField
        tmLabel                         matlab.ui.control.Label
        BsquedadecolisionesButtonGroup  matlab.ui.container.ButtonGroup
        FocalizadaButton                matlab.ui.control.ToggleButton
        OmnidireccionalButton           matlab.ui.control.ToggleButton
        TipodeelementosuspendidoButtonGroup  matlab.ui.container.ButtonGroup
        GanchoCargaButton               matlab.ui.control.ToggleButton
        GanchoButton                    matlab.ui.control.ToggleButton
        VisualizacinymonitorizacinPanel  matlab.ui.container.Panel
        CNNcargadaLamp                  matlab.ui.control.Lamp
        CNNcargadaLampLabel             matlab.ui.control.Label
        DetenerButton                   matlab.ui.control.StateButton
        EjecutarButton                  matlab.ui.control.Button
        SistemaactivoLamp               matlab.ui.control.Lamp
        SistemaactivoLampLabel          matlab.ui.control.Label
        RiesgodecolisinLamp             matlab.ui.control.Lamp
        RiesgodecolisinLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: OpcionesdevisualizacinButtonGroup
        function OpcionesdevisualizacinButtonGroupSelectionChanged(app, event)
           %selectedButton = app.OpcionesdevisualizacinButtonGroup.SelectedObject;
            opc1=app.NubedepuntosButton.Value;
            opc2=app.RielcarroganchoyocargaButton.Value;
            opc3=app.VolumendebsquedaycolisionesButton.Value;
            opc4=app.TrayectoriaseguidaporelganchoocargaButton.Value;

            if opc1==1
                app.RielcarroganchoyocargaButton.BackgroundColor=[0.96 0.96 0.96];
                app.NubedepuntosButton.BackgroundColor=[0.64 0.79 0.87];
                app.TrayectoriaseguidaporelganchoocargaButton.BackgroundColor=[0.96 0.96 0.96];
                app.VolumendebsquedaycolisionesButton.BackgroundColor=[0.96 0.96 0.96];
            end
            
            if opc2==1
                app.RielcarroganchoyocargaButton.BackgroundColor=[0.64 0.79 0.87];
                app.NubedepuntosButton.BackgroundColor=[0.64 0.79 0.87];
                app.TrayectoriaseguidaporelganchoocargaButton.BackgroundColor=[0.96 0.96 0.96];
                app.VolumendebsquedaycolisionesButton.BackgroundColor=[0.96 0.96 0.96];
            end

            if opc3==1
                app.RielcarroganchoyocargaButton.BackgroundColor=[0.64 0.79 0.87];
                app.NubedepuntosButton.BackgroundColor=[0.64 0.79 0.87];
                app.TrayectoriaseguidaporelganchoocargaButton.BackgroundColor=[0.96 0.96 0.96];
                app.VolumendebsquedaycolisionesButton.BackgroundColor=[0.64 0.79 0.87];
            end

           if opc4==1
                app.RielcarroganchoyocargaButton.BackgroundColor=[0.64 0.79 0.87];
                app.NubedepuntosButton.BackgroundColor=[0.64 0.79 0.87];
                app.TrayectoriaseguidaporelganchoocargaButton.BackgroundColor=[0.64 0.79 0.87];
                app.VolumendebsquedaycolisionesButton.BackgroundColor=[0.64 0.79 0.87];
            end 
        end

        % Selection changed function: TipodeelementosuspendidoButtonGroup
        function TipodeelementosuspendidoButtonGroupSelectionChanged(app, event)

        end

        % Selection changed function: BsquedadecolisionesButtonGroup
        function BsquedadecolisionesButtonGroupSelectionChanged(app, event)

            %selectedButton = app.BsquedadecolisionesButtonGroup.SelectedObject;
            bc1=app.OmnidireccionalButton.Value;
            if bc1==1
                app.Image3.ImageSource = 'Esfera.png';                
            else
                app.Image3.ImageSource = 'Prisma.png';
            end
        end

        % Value changed function: tmEditField
        function tmEditFieldValueChanged(app, event)

        end

        % Value changed function: dmEditField
        function dmEditFieldValueChanged(app, event)

        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
%FUNCION GENERAL QUE ACTIVA EL SISTEMA DE DETECCIÒN DE GANCHO, CARGAS Y
%COLISIONES
%-----------------------------------------------------------------------
load ('Red_entrenada.mat','net');
app.CNNcargadaLamp.Color='g';
drawnow;
bc1=app.OmnidireccionalButton.Value;
if bc1==1
    tipCol=1;
else
    tipCol=2;
end
%tipCol=1: Detector de colisiones omnidireccional (con base en esfera)
%tipCol=2: Detector de colisiones focalizado (con base en la direcciòn de
%          desplazamiento

te1=app.GanchoButton.Value;                    
if te1==1
    tipCarg=1;
else
    tipCarg=2;
end
%tipCarg=1: solo gancho
%tipCarg=2: gancho + carga

%opciones de visualización
ov2=app.RielcarroganchoyocargaButton.Value;
ov3=app.VolumendebsquedaycolisionesButton.Value;
ov4=app.TrayectoriaseguidaporelganchoocargaButton.Value;

if ov3==1
    ov2=1;
end

if ov4==1
    ov3=1;
    ov2=1;
end

tcarga = app.tmEditField.Value;  
%tcarga= tamaño estimado de la carga en ventana cuadrada 2*tcarga desde el centro

disSeg = app.dmEditField.Value; 
nnub=1;
%nnub= número de nubes a analizar

%--------------------------------------------------------

%Inicialización de variables
acufallo=0;
acufalcon=0;
detectGan=0;
y0car=0;
xriel0=0;
vy=0; vz=0;
xg0=0;yg0=0;zg0=0;
xc0=0;yc0=0;zc0=0;
dt=0.2;
t=zeros;
xgat=zeros; ygat=zeros; zgat=zeros;
hc0=0;rc0=0;
riesgprev=0;
i=0;

%--------------------------------------------------------
% Inicializacion de LIDAR

lidar = velodynelidar('VLP16');
start(lidar)

% Análisis de secuencia de nubes adquiridas en tiempo real

if tipCarg==1 %Solo gancho
    while nnub==1
        
        flush(lidar);       
        [frames,timestamps] = read(lidar,nnub);
        
        i=i+1;

        if i==1
            timestampsi=timestamps;
        end
        
        tact=etime(datevec(timestamps),datevec(timestampsi));
        t(i)=tact;
        if i>1
            dt=etime(datevec(timestamps),datevec(timestampsAnt));
        end
        timestampsAnt=timestamps;
        
        %Detección de riel y carro
        [xrield, ycar,nubfil]=EvaluaCNNsis (frames,y0car,xriel0,net,detectGan);
        
        %Detección y graficación puente grua con gancho
        [xg,yg,zg,vx,vy,vz,fallo,detectGan,acufalcon,obs] = detectGancho(nubfil,xrield,ycar,xg0,yg0,zg0,dt,vy,vz,detectGan,acufalcon);
        acufallo=acufallo+fallo;  
        y0car=yg;        
        xg0=xg; yg0=yg; zg0=zg;
        xgat(i)=xg;
        ygat(i)=yg;
        zgat(i)=zg;
        graficapuenteApp (app,xrield,yg,xg,yg,zg,nubfil,ov2)

        % DETECCIÒN DE COLISIONES
        %-----------------------------------------------------------------
        hcarg=0.25;
        radcarg=0.1;
        xcen=xg;
        ycen=yg;
        zcen=zg;
        if tipCol==1
            [riesg,ptRies,ptColis,rcab,resf] = DetectColOmnid(obs,hcarg,radcarg,xcen,ycen,zcen,xrield,xg,zg,disSeg,riesgprev);
            if ov3==1
                graficaObstOmniApp (app,riesg,xcen,ycen,zcen,xrield,ptRies,ptColis,rcab,resf);
            end
        else
            [riesg,ptRies,ptColis,priscab,priscar,fleVsisM] = DetectColFoc(obs,hcarg,radcarg,xcen,ycen,zcen,xrield,xg,zg,vx,vy,vz,disSeg,riesgprev);
            if ov3==1
                graficaObstFocApp (app,riesg,ptRies,ptColis,priscar,priscab,fleVsisM);
            end
        end
        % encendido de lamparas
        if detectGan>=1
            app.SistemaactivoLamp.Color='g';
        else
            app.SistemaactivoLamp.Color='r';
        end
        
        if detectGan>=1
           if riesg==0 
               app.RiesgodecolisinLamp.Color='g';
           elseif riesg==1
               app.RiesgodecolisinLamp.Color=[1 0.5 0];
           else
               app.RiesgodecolisinLamp.Color='r';
           end
        else
            app.RiesgodecolisinLamp.Color='r';
        end

        deten = app.DetenerButton.Value;
        drawnow;
        if deten==1
            app.DetenerButton.Text='Finalizado';
            break
        end
    end
    pause (0.01);
    if ov4==1
        plot3(app.UIAxes,xgat,ygat,zgat,'.','Color',[0 0.56 0.22],'markersize',5);
    end
   
else   %Gancho màs carga        
    while nnub==1
        
        flush(lidar);  

        [frames,timestamps] = read(lidar,nnub);
        
        i=i+1;
        if i==1
            timestampsi=timestamps;
        end
        
        tact=etime(datevec(timestamps),datevec(timestampsi));
        t(i)=tact;
        if i>1
            dt=etime(datevec(timestamps),datevec(timestampsAnt));
        end
        timestampsAnt=timestamps;
        %Detección de riel y carro
        [xrield, ycard,nubfil]=EvaluaCNNsis (frames,y0car,xriel0,net,detectGan);
        
        %Detección y graficación puente grua con gancho
        [xg,yg,zg,xc,yc,zc,vx,vy,vz,fallo,detectGan,acufalcon,hcarg,radcarg,obs] = detectCarga(nubfil,xrield,ycard,xg0,yg0,zg0,xc0,yc0,zc0,dt,vy,detectGan,acufalcon,hc0,rc0,tcarga);
        acufallo=acufallo+fallo;  
        xriel0=xrield;
        y0car=yg;        
        xg0=xg; yg0=yg; zg0=zg;
        xc0=xc; yc0=yc; zc0=zc; hc0=hcarg; 
        xgat(i)=xc;
        ygat(i)=yc;
        zgat(i)=zc;


        % DETECCIÒN DE COLISIONES
        %-----------------------------------------------------------------
        xcen=xc;
        ycen=yc;
        zcen=zc;
        if tipCol==1
            [riesg,ptRies,ptColis,rcab,resf] = DetectColOmnid(obs,hcarg,radcarg,xcen,ycen,zcen,xrield,xg,zg,disSeg,riesgprev);
             if riesg==2
                radcarg=rc0;
            end
            graficapuenteCargaApp (app,xrield,yg,radcarg,hcarg,xg,yg,zg,zc,nubfil,ov2)
            if ov3==1
                graficaObstOmniApp (app,riesg,xcen,ycen,zcen,xrield,ptRies,ptColis,rcab,resf);
            end
        else
            [riesg,ptRies,ptColis,priscab,priscar,fleVsisM] = DetectColFoc(obs,hcarg,radcarg,xcen,ycen,zcen,xrield,xg,zg,vx,vy,vz,disSeg,riesgprev);
             if riesg==2
                radcarg=rc0;
            end
            graficapuenteCargaApp (app,xrield,yg,radcarg,hcarg,xg,yg,zg,zc,nubfil,ov2)
            if ov3==1
                graficaObstFocApp (app,riesg,ptRies,ptColis,priscar,priscab,fleVsisM);
            end
        end
        riesgprev=riesg;

        % encendido de lamparas
        if detectGan>=1
            app.SistemaactivoLamp.Color='g';
        else
            app.SistemaactivoLamp.Color='r';
        end
        
        if detectGan>=1
           if riesg==0 
               app.RiesgodecolisinLamp.Color='g';
           elseif riesg==1
               app.RiesgodecolisinLamp.Color=[1 0.5 0];
           else
               app.RiesgodecolisinLamp.Color='r';
           end
        else
            app.RiesgodecolisinLamp.Color='r';
        end

        deten = app.DetenerButton.Value;
        drawnow;
        if deten==1
            app.DetenerButton.Text='Finalizado';
            break
        end 
        rc0=radcarg;
    end

    if ov4==1
        plot3(app.UIAxes,xgat,ygat,zgat,'.','color',[0 0.56 0.22],'markersize',5);
    end
end

stop(lidar)
clear lidar


clearvars 
        end

        % Callback function
        function CargarNubeButtonPushed(app, event)

        end

        % Callback function
        function ImageClicked(app, event)

        end

        % Callback function
        function DetenerButtonPushed(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: DetenerButton
        function DetenerButtonValueChanged(app, event)
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizacinymonitorizacinPanel = uipanel(app.UIFigure);
            app.VisualizacinymonitorizacinPanel.Title = 'Visualización y monitorización';
            app.UIAxes = uiaxes(app.VisualizacinymonitorizacinPanel);
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'y (m)')
            zlabel(app.UIAxes, 'z (m)')
            app.UIAxes.XLim = [-15 7];
            app.UIAxes.YLim = [2 14];
            app.UIAxes.ZLim = [-5 3];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RiesgodecolisinLabel = uilabel(app.VisualizacinymonitorizacinPanel);
            app.RiesgodecolisinLabel.Text = 'Riesgo de colisión';
            app.RiesgodecolisinLamp = uilamp(app.VisualizacinymonitorizacinPanel);
            app.SistemaactivoLampLabel = uilabel(app.VisualizacinymonitorizacinPanel);
            app.SistemaactivoLampLabel.Text = 'Sistema activo';
            app.SistemaactivoLamp = uilamp(app.VisualizacinymonitorizacinPanel);
            app.EjecutarButton = uibutton(app.VisualizacinymonitorizacinPanel, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.DetenerButton = uibutton(app.VisualizacinymonitorizacinPanel, 'state');
            app.DetenerButton.ValueChangedFcn = createCallbackFcn(app, @DetenerButtonValueChanged, true);
            app.DetenerButton.Text = 'Detener';
            app.CNNcargadaLampLabel = uilabel(app.VisualizacinymonitorizacinPanel);
            app.CNNcargadaLampLabel.Text = 'CNN cargada';
            app.CNNcargadaLamp = uilamp(app.VisualizacinymonitorizacinPanel);
            app.OpcionesdeconfiguracinPanel = uipanel(app.UIFigure);
            app.OpcionesdeconfiguracinPanel.Title = 'Opciones de configuración';
            app.TipodeelementosuspendidoButtonGroup = uibuttongroup(app.OpcionesdeconfiguracinPanel);
            app.TipodeelementosuspendidoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeelementosuspendidoButtonGroupSelectionChanged, true);
            app.TipodeelementosuspendidoButtonGroup.Title = 'Tipo de elemento suspendido';
            app.GanchoButton = uitogglebutton(app.TipodeelementosuspendidoButtonGroup);
            app.GanchoButton.Text = 'Gancho';
            app.GanchoButton.Value = true;
            app.GanchoCargaButton = uitogglebutton(app.TipodeelementosuspendidoButtonGroup);
            app.GanchoCargaButton.Text = 'Gancho + Carga';
            app.BsquedadecolisionesButtonGroup = uibuttongroup(app.OpcionesdeconfiguracinPanel);
            app.BsquedadecolisionesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BsquedadecolisionesButtonGroupSelectionChanged, true);
            app.BsquedadecolisionesButtonGroup.Title = 'Búsqueda de colisiones';
            app.OmnidireccionalButton = uitogglebutton(app.BsquedadecolisionesButtonGroup);
            app.OmnidireccionalButton.Text = 'Omnidireccional';
            app.OmnidireccionalButton.Value = true;
            app.FocalizadaButton = uitogglebutton(app.BsquedadecolisionesButtonGroup);
            app.FocalizadaButton.Text = 'Focalizada';
            app.TamaodebsquedaPanel = uipanel(app.OpcionesdeconfiguracinPanel);
            app.TamaodebsquedaPanel.Title = 'Tamaño de búsqueda';
            app.tmLabel = uilabel(app.TamaodebsquedaPanel);
            app.tmLabel.Text = 't (m):';
            app.tmEditField = uieditfield(app.TamaodebsquedaPanel, 'numeric');
            app.tmEditField.ValueChangedFcn = createCallbackFcn(app, @tmEditFieldValueChanged, true);
            app.tmEditField.Value = 0.75;
            app.Image2 = uiimage(app.TamaodebsquedaPanel);
            app.Image2.ImageSource = 'Ventana.png';
            app.DistanciadeseguridadPanel = uipanel(app.OpcionesdeconfiguracinPanel);
            app.DistanciadeseguridadPanel.Title = 'Distancia de seguridad';
            app.dmLabel = uilabel(app.DistanciadeseguridadPanel);
            app.dmLabel.Text = 'd (m):';
            app.dmEditField = uieditfield(app.DistanciadeseguridadPanel, 'numeric');
            app.dmEditField.ValueChangedFcn = createCallbackFcn(app, @dmEditFieldValueChanged, true);
            app.dmEditField.Value = 1;
            app.Image3 = uiimage(app.DistanciadeseguridadPanel);
            app.Image3.ImageSource = 'Esfera.png';
            app.OpcionesdevisualizacinButtonGroup = uibuttongroup(app.OpcionesdeconfiguracinPanel);
            app.OpcionesdevisualizacinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OpcionesdevisualizacinButtonGroupSelectionChanged, true);
            app.OpcionesdevisualizacinButtonGroup.Title = 'Opciones de visualización';
            app.NubedepuntosButton = uitogglebutton(app.OpcionesdevisualizacinButtonGroup);
            app.NubedepuntosButton.Text = 'Nube de puntos';
            app.NubedepuntosButton.Value = true;
            app.RielcarroganchoyocargaButton = uitogglebutton(app.OpcionesdevisualizacinButtonGroup);
            app.RielcarroganchoyocargaButton.Text = 'Riel, carro, gancho y/o carga';
            app.VolumendebsquedaycolisionesButton = uitogglebutton(app.OpcionesdevisualizacinButtonGroup);
            app.VolumendebsquedaycolisionesButton.Text = 'Volumen de bùsqueda y colisiones';
            app.TrayectoriaseguidaporelganchoocargaButton = uitogglebutton(app.OpcionesdevisualizacinButtonGroup);
            app.TrayectoriaseguidaporelganchoocargaButton.Text = 'Trayectoria seguida por el gancho o carga';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplicacion_1_tiempo_real

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end